classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ResetButton              matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        PlotTab                  matlab.ui.container.Tab
        Panel                    matlab.ui.container.Panel
        ProcessLabel             matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
        AnalysisTab              matlab.ui.container.Tab
        AnalysisTypeLabel        matlab.ui.control.Label
        FileNameEditField        matlab.ui.control.EditField
        FileNameEditFieldLabel   matlab.ui.control.Label
        TypeDropDown             matlab.ui.control.DropDown
        DataExportButton         matlab.ui.control.Button
        AnalyzeButton            matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        SelectColourButtonGroup  matlab.ui.container.ButtonGroup
        BlueButton               matlab.ui.control.RadioButton
        GreenButton              matlab.ui.control.RadioButton
        RedButton                matlab.ui.control.RadioButton
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        CalibrateButton          matlab.ui.control.Button
        WebCamSetupButton        matlab.ui.control.Button
        OperationsLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WebCamSetupButton
        function WebCamSetupButtonPushed(app, event)
            
            global stop;
            global colour_sel;
            stop =0;

            cam = webcam(2);

            img = snapshot(cam);

            redChannel = img(:,:,1); % Red channel
            greenChannel = img(:,:,2); % Green channel
            blueChannel = img(:,:,3); % Blue channel

            allBlack = zeros(size(img, 1), size(img, 2), 'uint8');% Create an all black channel.

            just_red = cat(3, redChannel, allBlack, allBlack);
            just_green = cat(3, allBlack, greenChannel, allBlack);
            just_blue = cat(3, allBlack, allBlack, blueChannel);

            if (colour_sel==1)
                imagesc(app.UIAxes,[0 0],[1280,1280],just_red);
                drawnow;
            elseif(colour_sel==2)
                imagesc(app.UIAxes,[0 0],[1280,1280],just_green);
                drawnow;
            elseif(colour_sel==3)
                imagesc(app.UIAxes,[0 0],[1280,1280],just_blue);
                drawnow;
            end


        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            global I_standard;
            global stop;
            global colour_sel;


            I_cal=0;
            text1 = sprintf('Calibrating\n');
            text2 = sprintf('Done Calibrating\n');
            app.EditField.Value = text1;

            for i=1:1000
                cam = webcam(2);
                img = snapshot(cam);

                if (colour_sel==1)
                    I_ins = sum(sum(img(:,:,1)))/(720*1280);
                elseif(colour_sel==2)
                    I_ins=sum(sum(img(:,:,2)))/(720*1280);
                elseif(colour_sel==3)
                    I_ins=sum(sum(img(:,:,3)))/(720*1280);
                end

                I_cal=[I_cal I_ins];

                pause(0.01)

                if (stop)
                    hold(app.UIAxes,"off");
                    break;
                end

            end

            app.EditField.Value = text2;


            I_standard= max(I_cal(10:end));

            val = sprintf('I_standard = %f',I_standard);
            app.EditField.Value = val;

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            %value = app.EditField.Value;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            global I_standard;
            global stop;
            global colour_sel;
            global I_str;
            global t_str;

            stop = 0;

            I_str = 0;
            t_str = datetime('now','TimeZone','local','Format','HH:mm:ss');

            cla(app.UIAxes,'reset');


            for i=1:10
                cam = webcam(2);
                img = snapshot(cam);

                t_ins = datetime('now','TimeZone','local','Format','HH:mm:ss');
                pause(0.5)

            end


            for i=1:1000
                cam = webcam(2);
                img = snapshot(cam);

                if (colour_sel==1)
                    I_ins = sum(sum(img(:,:,1)))/(720*1280);
                elseif(colour_sel==2)
                    I_ins=sum(sum(img(:,:,2)))/(720*1280);
                elseif(colour_sel==3)
                    I_ins=sum(sum(img(:,:,3)))/(720*1280);
                end

                if (I_ins < I_standard)
                    I_ins = I_standard;
                end

                t_ins = datetime('now','TimeZone','local','Format','HH:mm:ss');

                I_str=[I_str I_ins];

                t_str = [t_str t_ins];

                plot(app.UIAxes,t_ins,I_ins,'*');
                hold(app.UIAxes,"on");


                if (stop)
                    hold(app.UIAxes,"off");
                    break;
                end

                pause(0.5)

            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global stop;
            stop = 1;
        end

        % Selection changed function: SelectColourButtonGroup
        function SelectColourButtonGroupSelectionChanged(app, event)

            global colour_sel;

            option = app.SelectColourButtonGroup.SelectedObject;
            option.Text;

            if (option.Text=="Red")
                colour_sel = 1;
            elseif(option.Text=="Green")
                colour_sel = 2;
            elseif(option.Text=="Blue")
                colour_sel = 3;
            end


        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            global file_name;
            file_name = app.FileNameEditField.Value;
            
        end

        % Button pushed function: DataExportButton
        function DataExportButtonPushed(app, event)
            global file_name;
            global I_str;
            global t_str;
            global concentration;
            csvwrite("intensity.csv",I_str);
            csvwrite(file_name,concentration);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            global analysis_type;
            analysis_type = app.TypeDropDown.Value;
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            global analysis_type;
            global I_str;
            global t_str;
            global I_standard;
            global concentration;

            if(analysis_type == 1)
                concentration = 12* I_str(5:end)./I_standard;
                plot(app.UIAxes2,t_str(5:end),smooth(smooth(concentration)));
            elseif(analysis_type == 2)
                histogram(app.UIAxes2,concentration);
            end
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global analysis_type;
            global I_str;
            global t_str;
            global file_name;
            global colour_sel;
            global I_standard;
            global stop;
            
            analysis_type=1;
            I_str=0;
            t_str=0;
            file_name="";
            colour_sel=1;
            I_standard=0;
            stop=0;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OperationsLabel = uilabel(app.UIFigure);
            app.OperationsLabel.Text = 'Operations';
            app.WebCamSetupButton = uibutton(app.UIFigure, 'push');
            app.WebCamSetupButton.ButtonPushedFcn = createCallbackFcn(app, @WebCamSetupButtonPushed, true);
            app.WebCamSetupButton.Text = 'WebCam Setup';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SelectColourButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectColourButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectColourButtonGroupSelectionChanged, true);
            app.SelectColourButtonGroup.Title = 'Select Colour';
            app.RedButton = uiradiobutton(app.SelectColourButtonGroup);
            app.RedButton.Text = 'Red';
            app.RedButton.Value = true;
            app.GreenButton = uiradiobutton(app.SelectColourButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uiradiobutton(app.SelectColourButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.Panel = uipanel(app.PlotTab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Scattering Diagram')
            xlabel(app.UIAxes, {'Time'; ''})
            ylabel(app.UIAxes, 'Intensity')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2 2.25 2.5 2.75 3 3.25 3.5 3.75 4 4.25 4.5 4.75 5 5.25 5.5 5.75 6 6.25 6.5 6.75 7 7.25];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
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ProcessLabel = uilabel(app.Panel);
            app.ProcessLabel.Text = 'Process';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxes2 = uiaxes(app.AnalysisTab);
            title(app.UIAxes2, 'Concentration Vs time')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Concentratin(ug/m^3)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.YTick = [10 10.1 10.2 10.3 10.4 10.5 10.6 10.7 10.8 10.9 11 11.1 11.2 11.3 11.4 11.5 11.6 11.7 11.8 11.9 12 12.1 12.2 12.3 12.4 12.5 12.6 12.7 12.8 12.9 13 13.1 13.2 13.3 13.4 13.5 13.6 13.7 13.8 13.9 14 14.1 14.2 14.3 14.4 14.5 14.6 14.7 14.8 14.9 15 15.1 15.2 15.3 15.4 15.5 15.6 15.7 15.8 15.9 16 16.1 16.2 16.3 16.4 16.5 16.6 16.7 16.8 16.9 17 17.1 17.2 17.3 17.4 17.5 17.6 17.7 17.8 17.9 18 18.1 18.2 18.3 18.4 18.5 18.6 18.7 18.8 18.9 19 19.1 19.2 19.3 19.4 19.5 19.6 19.7 19.8 19.9 20];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.AnalyzeButton = uibutton(app.AnalysisTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.DataExportButton = uibutton(app.AnalysisTab, 'push');
            app.DataExportButton.ButtonPushedFcn = createCallbackFcn(app, @DataExportButtonPushed, true);
            app.DataExportButton.Text = 'Data Export';
            app.TypeDropDown = uidropdown(app.AnalysisTab);
            app.TypeDropDown.Items = {'Moving Average', 'Histogram'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Moving Average';
            app.FileNameEditFieldLabel = uilabel(app.AnalysisTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.AnalysisTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.AnalysisTypeLabel = uilabel(app.AnalysisTab);
            app.AnalysisTypeLabel.Text = {'Analysis'; 'Type'};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end